classdef guib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        select_sources         matlab.ui.container.ButtonGroup
        use_file               matlab.ui.control.RadioButton
        use_path               matlab.ui.control.RadioButton
        lamp_file              matlab.ui.control.Lamp
        lamp_path              matlab.ui.control.Lamp
        ui_get_file            matlab.ui.control.Button
        DATAmatLabel           matlab.ui.control.Label
        ui_get_path            matlab.ui.control.Button
        ui_autosave            matlab.ui.control.CheckBox
        I_FLabel               matlab.ui.control.Label
        I_F                    matlab.ui.control.NumericEditField
        I_ELabel               matlab.ui.control.Label
        I_E                    matlab.ui.control.NumericEditField
        I_SLabel               matlab.ui.control.Label
        I_S                    matlab.ui.control.NumericEditField
        T_FLabel               matlab.ui.control.Label
        T_F                    matlab.ui.control.NumericEditField
        T_ELabel               matlab.ui.control.Label
        T_E                    matlab.ui.control.NumericEditField
        T_SLabel               matlab.ui.control.Label
        T_S                    matlab.ui.control.NumericEditField
        selected_fname         matlab.ui.control.TextArea
        process_calc           matlab.ui.container.ButtonGroup
        ui_gauss_p             matlab.ui.control.RadioButton
        ui_spline_p            matlab.ui.control.RadioButton
        ui_pol_p               matlab.ui.control.NumericEditField
        process_grapf          matlab.ui.container.ButtonGroup
        ui_gauss_f             matlab.ui.control.RadioButton
        ui_spline_f            matlab.ui.control.RadioButton
        ui_pol_f               matlab.ui.control.NumericEditField
        ui_go                  matlab.ui.control.Button
        TLabel                 matlab.ui.control.Label
        Label_5                matlab.ui.control.Label
        ui_t_first             matlab.ui.control.NumericEditField
        ILabel                 matlab.ui.control.Label
        ui_i_first             matlab.ui.control.NumericEditField
        Label_6                matlab.ui.control.Label
        ui_t_last              matlab.ui.control.NumericEditField
        ui_i_last              matlab.ui.control.NumericEditField
        LLabel                 matlab.ui.control.Label
        ui_offset_l            matlab.ui.control.NumericEditField
        RLabel                 matlab.ui.control.Label
        ui_offset_r            matlab.ui.control.NumericEditField
        ui_custom_data         matlab.ui.control.CheckBox
        Label_4                matlab.ui.control.Label
        draw_grapf_source_sel  matlab.ui.container.ButtonGroup
        ui_draw_fwhm           matlab.ui.control.RadioButton
        ui_draw_peaks          matlab.ui.control.RadioButton
        ui_offset              matlab.ui.control.CheckBox
        ui_log                 matlab.ui.control.TextArea
        clear_logs             matlab.ui.control.Button
        resttore_defs          matlab.ui.control.Button
        draw_group             matlab.ui.container.ButtonGroup
        ui_draw_f              matlab.ui.control.RadioButton
        ui_draw_o              matlab.ui.control.RadioButton
        ui_draw_i              matlab.ui.control.RadioButton
        progress               matlab.ui.control.Gauge
    end

    
    properties (Access = private)
        fpath;
        fname;
        dname = "DATA.mat";
    end
    
    
    methods (Access = private)
        
        function [data] = get_data_from_path(app,path)
            y = (abs(app.T_E.Value-app.T_F.Value)/app.T_S.Value)+1;
            x = (abs(app.I_E.Value-app.I_F.Value)/app.I_S.Value)+1;
            data{x,y} = 0;
            t = 0;
            for T=app.T_F.Value:app.T_S.Value:app.T_E.Value
                i=0;
                t=t+1;
                for I=app.I_F.Value:app.I_S.Value:app.I_E.Value
                    i=i+1;
                    data{i,t} = table2array(readtable(path+"\"+T+"\"+I+".DAT"));
                end
            end
            if (app.ui_autosave.Value)
                save(path+"\"+app.dname,'data');
                add_log(app,path+"\"+app.dname+" created and saved!");
            end
        end
        
        function [data] = checkdata(app)
            if (app.use_file.Value) % file
                if (isempty(app.fname))
                    if exist(app.dname,'file')
                        name = app.dname;
                    else
                        name = uigetfile('*.mat');
                    end
                else
                   name = app.fname;
                end
                data = matfile(name).data;
                show_selected(app,name);
                add_log(app,"Reading "+name);
            else % path
                if (isempty(app.fpath))
                    path = uigetdir;
                else
                    if exist(app.fpath,'dir')
                        path = app.fpath;
                    else
                        path = uigetdir;
                    end
                end
                data = get_data_from_path(app,path);
                show_selected(app,path);
                add_log(app,"Reading "+path);
            end
        end
        
        function [M] = find_points(~,x1,y1,y2)
            id = find( abs(diff(sign(y2 - y1)))>0 );
            M = zeros(length(id),2);
            for i = 1:length(id)
                j = id(i); % индекс левой пары точек перед пересечением
                k1 = (y1(j+1)-y1(j))/(x1(j+1)-x1(j));
                k2 = 0;
                b1 = y1(j) - k1*x1(j);
                b2 = y2;
                A = [k1, -1; % матрица коэф-тов
                    k2, -1];
                B = [-b1;  % столбец левой части
                    -b2];
                u = A\B; % решаем систему
                % сохраняем координаты:
                M(i,:) = u;
            end
        end
        
        function [x,y] = interpol(~,x_old,y_old,pol,step)
            [p,~,mu] = polyfit(x_old,y_old,pol);
            x = x_old(1):step:x_old(end);
            y = polyval(p,x,[],mu);
        end
        
        function [r,l,wtf] = find_rl_index(~,M,x_ymax)
            m = M(:,1) - x_ymax;
            r = find(m>0, 1 );
            l = find(m<0, 1, 'last' );
            if (isempty(r) || isempty(l))
                wtf = true;
            else
                wtf = false;
            end
        end
        
        function [z_i] = zi(~,i)
            z_i = i*100+300;
        end
        
        function [z_t] = zt(~,t)
            z_t = t*5+10;
        end
        
        function plot_hor(~,x,y,dname)
            plot([x(1) x(end)],[y y],'DisplayName',dname);
        end
        
        function final(app,FWHM,X_max,name,p,forp)
            assignin("base","FWHM",FWHM);
            [ie,te] = size(FWHM);
            point = ["." "+" "*" "o" "x"];
            color = ["r" "c" "g" "b" "m"];
            figure('Name',name,'NumberTitle','off');
            grid on;
            hold on;
            i = 1:ie;
            i2 = 1:0.1:ie;
            for t=1:te
                if (forp)
                    x = FWHM(:,t);
                    xln = "FWHM";
                else
                    x = X_max(:,t);
                    xln = "x";
                end
                if (p)
                    [~,y] = interpol(app,i,x,p,0.1);
                else
                    y = interp1(i,x,i2,'spline');
                end
                plot(zi(app,i2), y, color(t),'DisplayName', '');
                plot(zi(app,i), x, point(t)+color(t),'DisplayName', num2str(zt(app,t)));
            end
            hold off;
            
            title(legend('Location','best'),'T, °C');
            xlabel('I, mA');
            ylabel(xln+", nm");
        end
        
        function add_log(app,new_str)
            app.ui_log.Value = [app.ui_log.Value;new_str];
            drawnow;
        end
        
        function check_one_g(app)
            if (app.ui_t_first.Value == app.ui_t_last.Value)
                app.ui_draw_t.Value = 1;
                if (app.ui_i_first.Value == app.ui_i_last.Value)
                    app.ui_draw_i.Value = 1;
                end
            end
        end
        
        function check_lamp(app)
            app.lamp_file.Visible = app.use_file.Value;
            app.lamp_path.Visible = app.use_path.Value;
            app.lamp_file.Enable = exist(app.dname,'file');
            app.lamp_path.Enable = exist((app.fpath),'dir');
        end
        
        function show_selected(app,str)
            app.selected_fname.Value = str;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, b)
            check_lamp(app);
            if exist("DATA.mat",'file')
                show_selected(app,app.dname);
            end
        end

        % Button pushed function: ui_go
        function ui_goButtonPushed(app, event)
            clear_logsButtonPushed(app,event);
            app.progress.Value = 0;
            
            DATA = checkdata(app);
            assignin("base","DATA",DATA);
            full_num = length(DATA{1,1});
            
            % default vars start
            gauss    = app.ui_gauss_p.Value;  % использовать Гауссову интерполяцию
            spline   = app.ui_spline_p.Value; % исgользовать интерполяцию сплайнами
            draw_f   = app.ui_draw_f.Value;   % рисовать каждый график для текущих T,I
            draw_i   = app.ui_draw_i.Value;   % рисовать каждый график для текущих T,I
            draw_o   = app.ui_draw_o.Value;   % рисовать исходные графики для T
            pol_g    = app.ui_pol_p.Value;    % степень полинома для интерполяции
            pol_f    = app.ui_pol_f.Value;    % степень полинома для финального графика [0..i_last]|false
            offset_l = app.ui_offset_l.Value; % промежуток справа от пика
            offset_r = app.ui_offset_r.Value; % промежуток слева  от пика
            shift    = app.ui_offset.Value;
            t_first  = 1;
            i_first  = 1;
            [i_last, t_last] = size(DATA);
            % default vars end
            
            if (app.ui_custom_data.Value)
                t_first = app.ui_t_first.Value;
                i_first = app.ui_i_first.Value;
                t_last  = app.ui_t_last.Value;
                i_last  = app.ui_i_last.Value;
            end
            
            FWHM = zeros(i_last,t_last);
            X_max = zeros(i_last,t_last);
            
            if (app.ui_spline_f.Value)
                pol_f = 0;
            end
            
            itters = (t_last-t_first+1)*(i_last-i_first+1);
            if (draw_o)
                fig_num = t_last;
            elseif (draw_i)
                fig_num = itters;
            else
                fig_num = "final";
            end
            
            if (draw_i && (fig_num > 20))
                uic = uifigure;
                message = "Будет нарисовано "+fig_num+" графиков";
                conf = uiconfirm(uic,message,"Проложить?",'CloseFcn',@(h,e) close(uic));
                if (conf ~= "OK")
                    draw_i = false;
                end
            end
            
            count = 0;
            add_log(app,"Print "+fig_num+" figures");
            for t=t_first:t_last
                name_t = zt(app,t);
                if (draw_o)
                    figure('Name',"T="+name_t,'NumberTitle','off');
                    hold on;
                end
                for i=i_first:i_last
                    name_i = zi(app,i);
                    [~, index_max] = max(DATA{i,t}(:,2));
                    
                    x_left  = index_max - offset_l;
                    x_right = index_max + offset_r;
                    zoom = (x_left:x_right).';
                    
                    if (shift)
                        shift_x = DATA{i,t}(1,1) - DATA{i,t}(index_max,1);
                    else
                        shift_x = 0;
                    end
                    shift_y = (sum(DATA{i,t}(:,2)) - sum(DATA{i,t}(zoom,2)))/(full_num-offset_l-offset_r-1);
                    
                    zoom_x = DATA{i,t}(zoom,1) + shift_x; % смещение по x
                    zoom_y = DATA{i,t}(zoom,2) - shift_y; % смещение по y
                    
                    c_left_x = (1:x_left).';
                    c_left_y = DATA{i,t}(c_left_x,2) - shift_y;
                    
                    c_right_x = (x_right:full_num).';
                    c_right_y = DATA{i,t}(c_right_x,2) - shift_y;
                    base = ((sum(c_left_y) + sum(c_right_y)) / (length(c_left_x) + length(c_right_x)));  % база
                    
                    [y_max, h_index] = max(zoom_y);
                    half_y = abs(y_max - base)/2;
                    
                    M0 = find_points(app,zoom_x,zoom_y,half_y);
                    
                    if (gauss) % gauss
                        
                        [zoom_x2,zoom_y2] = interpol(app,zoom_x,zoom_y,pol_g,0.01);
                        
                        M2 = find_points(app,zoom_x2,zoom_y2,half_y);
                        if (length(M2) < 2)
                            M2 = M0;
                        end
                        M = M2;
                    elseif (spline) % spline
                        zoom_x2 = zoom_x(1):0.01:zoom_x(end);
                        zoom_y2 = interp1(zoom_x,zoom_y,zoom_x2,'spline');
                        M = find_points(app,zoom_x2,zoom_y2,half_y);
                    else
                        M = M0;
                    end
                    
                    x_ymax = zoom_x(h_index);
                    
                    [m_min_r_index, m_min_l_index, wtf] = find_rl_index(app,M,x_ymax);
                    if (wtf)
                        add_log(app,"wtf: T="+name_t+", I="+name_i);
                        [m_min_r_index, m_min_l_index, ~] = find_rl_index(app,M0,x_ymax);
                        M = M0;
                    end
                    
                    FWHM(i,t) = abs(M(m_min_r_index,1)-M(m_min_l_index,1)); % FWHM
                    X_max(i,t) = DATA{i,t}(zoom(h_index),1);
                    
                    if (draw_o)
                        plot(DATA{i,t}(:,1),DATA{i,t}(:,2),'DisplayName',""+name_i);
                        %         plot([zoom_x(end) zoom_x(end)],[half_y 0]);
                        %         plot([DATA{i,t}(x_left,1) DATA{i,t}(x_left,1)],[0.6 0.8]);
                    elseif (draw_i)
                        figure('Name',t+":"+i+" T="+name_t+",I="+name_i,'NumberTitle','off');
                        title("T="+name_t+",I="+name_i);
                        hold on;
                        plot_hor(app,zoom_x,base,'base');
                        plot(zoom_x,zoom_y,'DisplayName','zoomed');
                        %         findpeaks(zoom_y,zoom_x,'Annotate','extents','WidthReference','halfheight')
                        if (gauss || spline)
                            plot(zoom_x2,zoom_y2,'-.','DisplayName','apprx');
                            %         findpeaks(zoom_y2,zoom_x2,'Annotate','extents','WidthReference','halfheight');
                        end
                        plot_hor(app,zoom_x,half_y,'half y');
                        plot(M([m_min_l_index,m_min_r_index],1),M([m_min_l_index,m_min_r_index],2),'*g','DisplayName','L R points');
                        legend('Location','best');
                        hold off;
                    end
                    count = count+1;
                    app.progress.Value = round(count*100/itters);
                end
                if (draw_o)
                    hold off;
                    legend('Location','best');
                    title("T="+name_t);
                end
            end
            
            if (draw_f)
                if (gauss)
                    name = "gauss"+pol_g;
                elseif (spline)
                    name = "spline";
                else
                    name = "";
                end
                if (pol_f)
                    name = name+":gauss"+pol_f;
                else
                    name = name+":spline";
                end
                final(app,FWHM(i_first:i_last,t_first:t_last),X_max(i_first:i_last,t_first:t_last),...
                    name,pol_f,app.ui_draw_fwhm.Value);
            end
            
        end

        % Button pushed function: ui_get_path
        function ui_get_pathButtonPushed(app, event)
            app.fpath = uigetdir;
            check_lamp(app);
            show_selected(app,app.fpath);
        end

        % Button pushed function: ui_get_file
        function ui_get_fileButtonPushed(app, event)
            [name,path] = uigetfile('*.mat');
            app.fname = ""+path+name;
            check_lamp(app);
            show_selected(app,app.fname);
        end

        % Selection changed function: draw_grapf_source_sel
        function draw_grapf_source_selSelectionChanged(app, event)
            peaks = app.ui_draw_peaks.Value;
            app.ui_spline_f.Value = peaks;
            app.ui_spline_p.Value = peaks;
            app.ui_pol_p.Enable = ~peaks;
            app.ui_pol_f.Enable = ~peaks;
        end

        % Value changed function: ui_custom_data
        function ui_custom_dataValueChanged(app, event)
            vis = app.ui_custom_data.Value;
            app.ui_i_first.Enable = vis;
            app.ui_t_first.Enable = vis;
            app.ui_i_last.Enable  = vis;
            app.ui_t_last.Enable  = vis;
        end

        % Selection changed function: process_calc
        function process_calcSelectionChanged(app, event)
            app.ui_pol_p.Enable = app.ui_gauss_p.Value;
        end

        % Selection changed function: process_grapf
        function process_grapfSelectionChanged(app, event)
            app.ui_pol_f.Enable = app.ui_gauss_f.Value;
        end

        % Value changed function: ui_i_first, ui_i_last, 
        % ui_t_first, ui_t_last
        function ui_t_firstValueChanged(app, event)
            check_one_g(app);
        end

        % Selection changed function: select_sources
        function select_sourcesSelectionChanged(app, event)
            var_use_file = app.use_file.Value;
            var_use_path = app.use_path.Value;
            app.ui_get_file.Enable = var_use_file;
            app.ui_get_path.Enable = var_use_path;
            app.T_F.Enable = var_use_path;
            app.T_E.Enable = var_use_path;
            app.T_S.Enable = var_use_path;
            app.I_F.Enable = var_use_path;
            app.I_E.Enable = var_use_path;
            app.I_S.Enable = var_use_path;
            app.ui_autosave.Enable = var_use_path;
            check_lamp(app);
            clear_logsButtonPushed(app,event);
            show_selected(app,"");
        end

        % Button pushed function: clear_logs
        function clear_logsButtonPushed(app, event)
            app.ui_log.Value = "Logs:";
            app.progress.Value = 0;
        end

        % Button pushed function: resttore_defs
        function resttore_defsButtonPushed(app, event)
            app.progress.Value = 0;
            clear_logsButtonPushed(app,event);
            [app.use_file.Value,...
             app.ui_gauss_p.Value,...
             app.ui_gauss_f.Value,...
             app.ui_draw_fwhm.Value,...
             app.ui_draw_f.Value,...
             app.ui_offset.Value] = deal(true);
            [app.T_F.Value,app.T_E.Value,app.T_S.Value,...
             app.I_F.Value,app.I_E.Value,app.I_S.Value] = deal(15,35,5,400,2100,100);
            [app.ui_pol_p.Value,app.ui_offset_l.Value,...
             app.ui_pol_f.Value,app.ui_offset_r.Value] = deal(18,25,5,35);
             app.ui_custom_data.Value = false;
            [app.ui_t_first.Value,app.ui_t_last.Value,...
             app.ui_i_first.Value,app.ui_i_last.Value] = deal(2,3,5,16);
            ui_custom_dataValueChanged(app,event);
            select_sourcesSelectionChanged(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.select_sources = uibuttongroup(app.UIFigure);
            app.select_sources.SelectionChangedFcn = createCallbackFcn(app, @select_sourcesSelectionChanged, true);
            app.select_sources.Title = 'Источник';
            app.use_file = uiradiobutton(app.select_sources);
            app.use_file.Text = '';
            app.use_file.Value = true;
            app.use_path = uiradiobutton(app.select_sources);
            app.use_path.Text = '';
            app.lamp_file = uilamp(app.select_sources);
            app.lamp_file.Visible = 'off';
            app.lamp_path = uilamp(app.select_sources);
            app.lamp_path.Enable = 'off';
            app.lamp_path.Visible = 'off';
            app.ui_get_file = uibutton(app.select_sources, 'push');
            app.ui_get_file.ButtonPushedFcn = createCallbackFcn(app, @ui_get_fileButtonPushed, true);
            app.ui_get_file.Text = 'Выбрать файл';
            app.DATAmatLabel = uilabel(app.select_sources);
            app.DATAmatLabel.Text = 'По умолчанию DATA.mat в текущей папке';
            app.ui_get_path = uibutton(app.select_sources, 'push');
            app.ui_get_path.ButtonPushedFcn = createCallbackFcn(app, @ui_get_pathButtonPushed, true);
            app.ui_get_path.Enable = 'off';
            app.ui_get_path.Text = 'Выбрать папку';
            app.ui_autosave = uicheckbox(app.select_sources);
            app.ui_autosave.Enable = 'off';
            app.ui_autosave.Text = 'Записать данные в DATA.mat';
            app.ui_autosave.Value = true;
            app.I_FLabel = uilabel(app.select_sources);
            app.I_FLabel.Text = 'I от';
            app.I_F = uieditfield(app.select_sources, 'numeric');
            app.I_F.Limits = [0 2100];
            app.I_F.Enable = 'off';
            app.I_F.Value = 400;
            app.I_ELabel = uilabel(app.select_sources);
            app.I_ELabel.Text = 'до';
            app.I_E = uieditfield(app.select_sources, 'numeric');
            app.I_E.Limits = [0 2100];
            app.I_E.Enable = 'off';
            app.I_E.Value = 2100;
            app.I_SLabel = uilabel(app.select_sources);
            app.I_SLabel.Text = 'с шагом';
            app.I_S = uieditfield(app.select_sources, 'numeric');
            app.I_S.Limits = [5 500];
            app.I_S.Enable = 'off';
            app.I_S.Value = 100;
            app.T_FLabel = uilabel(app.select_sources);
            app.T_FLabel.Text = 'T от';
            app.T_F = uieditfield(app.select_sources, 'numeric');
            app.T_F.Limits = [-20 50];
            app.T_F.Enable = 'off';
            app.T_F.Value = 15;
            app.T_ELabel = uilabel(app.select_sources);
            app.T_ELabel.Text = 'до';
            app.T_E = uieditfield(app.select_sources, 'numeric');
            app.T_E.Limits = [-15 50];
            app.T_E.Enable = 'off';
            app.T_E.Value = 35;
            app.T_SLabel = uilabel(app.select_sources);
            app.T_SLabel.Text = 'с шагом';
            app.T_S = uieditfield(app.select_sources, 'numeric');
            app.T_S.Limits = [0 10];
            app.T_S.Enable = 'off';
            app.T_S.Value = 5;
            app.selected_fname = uitextarea(app.select_sources);
            app.selected_fname.Editable = 'off';
            app.process_calc = uibuttongroup(app.UIFigure);
            app.process_calc.SelectionChangedFcn = createCallbackFcn(app, @process_calcSelectionChanged, true);
            app.process_calc.Title = 'Обработка результатов';
            app.ui_gauss_p = uiradiobutton(app.process_calc);
            app.ui_gauss_p.Tag = 'gauss';
            app.ui_gauss_p.Text = 'Гаусс';
            app.ui_gauss_p.Value = true;
            app.ui_spline_p = uiradiobutton(app.process_calc);
            app.ui_spline_p.Tag = 'spline';
            app.ui_spline_p.Text = 'Сплайны';
            app.ui_pol_p = uieditfield(app.process_calc, 'numeric');
            app.ui_pol_p.Limits = [5 25];
            app.ui_pol_p.Value = 18;
            app.process_grapf = uibuttongroup(app.UIFigure);
            app.process_grapf.SelectionChangedFcn = createCallbackFcn(app, @process_grapfSelectionChanged, true);
            app.process_grapf.Title = 'Обработка графика';
            app.ui_gauss_f = uiradiobutton(app.process_grapf);
            app.ui_gauss_f.Text = 'Гаусс';
            app.ui_gauss_f.Value = true;
            app.ui_spline_f = uiradiobutton(app.process_grapf);
            app.ui_spline_f.Text = 'Сплайны';
            app.ui_pol_f = uieditfield(app.process_grapf, 'numeric');
            app.ui_pol_f.Limits = [1 20];
            app.ui_pol_f.Value = 5;
            app.ui_go = uibutton(app.UIFigure, 'push');
            app.ui_go.ButtonPushedFcn = createCallbackFcn(app, @ui_goButtonPushed, true);
            app.ui_go.Text = 'Го';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'От';
            app.ui_t_first = uieditfield(app.UIFigure, 'numeric');
            app.ui_t_first.Limits = [1 5];
            app.ui_t_first.ValueChangedFcn = createCallbackFcn(app, @ui_t_firstValueChanged, true);
            app.ui_t_first.Enable = 'off';
            app.ui_t_first.Value = 2;
            app.ILabel = uilabel(app.UIFigure);
            app.ILabel.Text = 'I';
            app.ui_i_first = uieditfield(app.UIFigure, 'numeric');
            app.ui_i_first.Limits = [1 18];
            app.ui_i_first.ValueChangedFcn = createCallbackFcn(app, @ui_t_firstValueChanged, true);
            app.ui_i_first.Enable = 'off';
            app.ui_i_first.Value = 5;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'До';
            app.ui_t_last = uieditfield(app.UIFigure, 'numeric');
            app.ui_t_last.Limits = [1 5];
            app.ui_t_last.ValueChangedFcn = createCallbackFcn(app, @ui_t_firstValueChanged, true);
            app.ui_t_last.Enable = 'off';
            app.ui_t_last.Value = 3;
            app.ui_i_last = uieditfield(app.UIFigure, 'numeric');
            app.ui_i_last.Limits = [1 18];
            app.ui_i_last.ValueChangedFcn = createCallbackFcn(app, @ui_t_firstValueChanged, true);
            app.ui_i_last.Enable = 'off';
            app.ui_i_last.Value = 16;
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L';
            app.ui_offset_l = uieditfield(app.UIFigure, 'numeric');
            app.ui_offset_l.Limits = [10 100];
            app.ui_offset_l.Value = 25;
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R';
            app.ui_offset_r = uieditfield(app.UIFigure, 'numeric');
            app.ui_offset_r.Limits = [10 100];
            app.ui_offset_r.Value = 35;
            app.ui_custom_data = uicheckbox(app.UIFigure);
            app.ui_custom_data.ValueChangedFcn = createCallbackFcn(app, @ui_custom_dataValueChanged, true);
            app.ui_custom_data.Text = 'Выборка';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Ширина от пика';
            app.draw_grapf_source_sel = uibuttongroup(app.UIFigure);
            app.draw_grapf_source_sel.SelectionChangedFcn = createCallbackFcn(app, @draw_grapf_source_selSelectionChanged, true);
            app.draw_grapf_source_sel.Title = 'Построение графика';
            app.ui_draw_fwhm = uiradiobutton(app.draw_grapf_source_sel);
            app.ui_draw_fwhm.Text = 'FWHM';
            app.ui_draw_fwhm.Value = true;
            app.ui_draw_peaks = uiradiobutton(app.draw_grapf_source_sel);
            app.ui_draw_peaks.Text = 'Пики';
            app.ui_offset = uicheckbox(app.UIFigure);
            app.ui_offset.Text = 'Сдвиг по x';
            app.ui_offset.Value = true;
            app.ui_log = uitextarea(app.UIFigure);
            app.ui_log.Editable = 'off';
            app.ui_log.Value = {'Logs:'};
            app.clear_logs = uibutton(app.UIFigure, 'push');
            app.clear_logs.ButtonPushedFcn = createCallbackFcn(app, @clear_logsButtonPushed, true);
            app.clear_logs.Text = 'Очистить';
            app.resttore_defs = uibutton(app.UIFigure, 'push');
            app.resttore_defs.ButtonPushedFcn = createCallbackFcn(app, @resttore_defsButtonPushed, true);
            app.resttore_defs.Text = 'По умолчанию';
            app.draw_group = uibuttongroup(app.UIFigure);
            app.draw_group.Title = 'Рисовать';
            app.ui_draw_f = uiradiobutton(app.draw_group);
            app.ui_draw_f.Text = 'Финал';
            app.ui_draw_f.Value = true;
            app.ui_draw_o = uiradiobutton(app.draw_group);
            app.ui_draw_o.Text = 'Оригинальные для T';
            app.ui_draw_i = uiradiobutton(app.draw_group);
            app.ui_draw_i.Text = 'Отдельно для I,T';
            app.progress = uigauge(app.UIFigure, 'circular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guib(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end